classdef detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Input            matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        ResultLabel      matlab.ui.control.Label
        DetectButton     matlab.ui.control.Button
        LoadModelButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ModelFile  % 存储最佳GMM模型文件路径
        DataFile  % 存储预处理后的数据文件路径
        GMMModels  % 存储GMM模型
        Coeff      % 存储PCA变换矩阵
        Features   % 存储预处理后的特征
        BestReducedDimension % 存储最佳PCA降维数
    end
    
    methods (Access = private, Static)
        function [st_am_features, st_zcr_features] = extractFeatures(frames)
            % 平滑参数
            windowSize = 5;

            % 提取短时平均幅度特征
            st_am = movmean(mean(abs(frames), 2), windowSize);
            st_am_stats = [mean(st_am), std(st_am), min(st_am), max(st_am)];
            st_am_features = (st_am_stats);

            % 提取短时过零率特征
            zcr = sum(abs(diff(frames > 0, 1, 2)), 2) / (2 * size(frames, 2));
            smoothed_zcr = movmean(zcr, windowSize);
            zcr_stats = [mean(smoothed_zcr), std(smoothed_zcr), min(smoothed_zcr), max(smoothed_zcr)];
            st_zcr_features = (zcr_stats);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select the best GMM model file');
            if isequal(filename, 0)
                return;
            end
            app.ModelFile = fullfile(pathname, filename);
            load(app.ModelFile, 'gmmModels', 'coeff', 'bestReducedDimension');
            app.GMMModels = gmmModels;
            app.Coeff = coeff;
            app.BestReducedDimension = bestReducedDimension;
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select a preprocessed data file');
            if isequal(filename, 0)
                return;
            end
            app.DataFile = fullfile(pathname, filename);
            load(app.DataFile, 'frames');
            app.Features = frames;
            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            % 加载特征数据
            load('energy_features.mat');
            load('intensity_features.mat');
            load('mfcc_features.mat');
            load('harmonic_ratio_features.mat');
            load('transition_ratio_features.mat');
            load('st_am_features.mat');
            load('st_zcr_features.mat');

            % 准备数据和标签
            features = [st_am_features(app.Input.Value,:), st_zcr_features(app.Input.Value,:)];

            % 使用PCA降维
            features_pca = features * app.Coeff(:, 1:app.BestReducedDimension);

            % 对特征数据进行预测
            log_likelihoods = zeros(4, 1);

            for j = 1:4
                log_likelihoods(j) = sum(log(pdf(app.GMMModels{j}, features_pca)));
            end

            [~, labels_pred] = max(log_likelihoods);

            % 输出预测结果
            app.ResultLabel.Text = sprintf('疲劳等级为: %d', labels_pred);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadModelButton = uibutton(app.UIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = '导入模型';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = '检测分类';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请输入要检测的音频序号';
            app.Input = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end