classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ResultLabel      matlab.ui.control.Label
        DetectButton     matlab.ui.control.Button
        loadModelButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ModelPath (1, :) string
        DataPath (1, :) string
        Frames double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadModelButton
        function loadModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', '选择模型文件');
            if ~isequal(filename, 0)
                app.ModelPath = fullfile(pathname, filename);
            else
                app.ModelPath = '';
            end
        end

        % Callback function
        function ImportButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', '选择待测数据文件');
            if ~isequal(filename, 0)
                app.DataPath = fullfile(pathname, filename);
                loadedData = load(app.DataPath);
                app.Frames = loadedData.frames;
            else
                app.DataPath = '';
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            % 加载特征数据
            load('energy_features.mat');
            load('intensity_features.mat');
            load('mfcc_features.mat');
            load('harmonic_ratio_features.mat');
            load('transition_ratio_features.mat');
            load('st_am_features.mat');
            load('st_zcr_features.mat');

            % 准备数据和标签
            features = [st_am_features, st_zcr_features];
            labels = repmat([1, 2, 3, 4], 1, 9)';

            % 加载最佳GMM模型和PCA系数
            load('best_gmm_model.mat', 'gmmModels', 'coeff', 'bestReducedDimension');

            % 使用PCA降维
            features_pca = features * coeff(:, 1:bestReducedDimension);

            % 对整个数据集进行预测
            labels_pred = zeros(size(labels));

            for i = 1:length(labels)
                log_likelihoods = zeros(4, 1);

                for j = 1:4
                    log_likelihoods(j) = sum(log(pdf(gmmModels{j}, features_pca(i, :))));
                end

                [~, labels_pred(i)] = max(log_likelihoods);
            end

            % 计算分类准确率
            accuracy = sum(labels_pred == labels) / length(labels);
            fprintf('整个数据集的准确率：%.2f%%\n', accuracy * 100);

            % 在App中的某个文本组件上显示结果
            app.ResultLabel.Text = sprintf('整个数据集的准确率：%.2f%%', accuracy * 100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadModelButton = uibutton(app.UIFigure, 'push');
            app.loadModelButton.ButtonPushedFcn = createCallbackFcn(app, @loadModelButtonPushed, true);
            app.loadModelButton.Text = '导入模型';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = '检测准确率';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end