classdef show < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ExtractFeaturesButton  matlab.ui.control.Button
        ImportAudioButton      matlab.ui.control.Button
        FeatureDropDown        matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        Axes2                  matlab.ui.control.UIAxes
        Axes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioData;
        fs;
        features; 
    end


    methods (Access = public)
        function features = extractAudioFeatures(app, audioData, fs)
            % 参数设置
            nFFT = 512;
            frameSize = 256; % 设定帧大小
            frameShift = 128; % 设定帧移
            nMFCC = 13; % 提取的MFCC特征数量

            % 转换为单通道音频
            audioDataSingleChannel = audioData(:, 1);

            % 对音频数据进行分帧和窗口处理
            numFrames = floor((length(audioDataSingleChannel) - frameSize) / frameShift) + 1;
            frames = zeros(frameSize, numFrames);
            for i = 1:numFrames
                startIndex = (i - 1) * frameShift + 1;
                endIndex = startIndex + frameSize - 1;
                frames(:, i) = audioDataSingleChannel(startIndex:endIndex);
            end
            frames = frames .* hann(frameSize, 'periodic'); % 应用汉宁窗

            % 存储特征的变量
            features = struct('intensity', [], 'energy', [], 'mfcc', [], ...
                'harmonic_ratio', [], 'transition_ratio', [], ...
                'st_am', [], 'st_zcr', []);

            % 平滑参数
            windowSize = 5;

            % 提取强度特征
            smoothed_intensity = movmean(max(abs(frames), [], 2), windowSize);
            intensity_stats = [mean(smoothed_intensity), std(smoothed_intensity), ...
                min(smoothed_intensity), max(smoothed_intensity)];
            features.intensity = intensity_stats;

            % 提取短时能量特征
            smoothed_energy = movmean(sum(frames.^2, 2), windowSize);
            energy_stats = [mean(smoothed_energy), std(smoothed_energy), ...
                min(smoothed_energy), max(smoothed_energy)];
            features.energy = energy_stats;

            % 提取短时平均幅度特征
            st_am = movmean(mean(abs(frames), 2), windowSize);
            st_am_stats = [mean(st_am), std(st_am), min(st_am), max(st_am)];
            features.st_am = st_am_stats;

            % 提取短时过零率特征
            zcr = sum(abs(diff(frames > 0, 1, 2)), 2) / (2 * frameSize);
            smoothed_zcr = movmean(zcr, windowSize);
            zcr_stats = [mean(smoothed_zcr), std(smoothed_zcr), ...
                min(smoothed_zcr), max(smoothed_zcr)];
            features.st_zcr = zcr_stats;

            % 提取MFCC特征
            mfcc = melcepst(frames, fs, '0dD', nMFCC, floor(3*log(fs)), nFFT);
            mfcc_mean = mean(mfcc, 1);
            mfcc_std = std(mfcc, 1);
            mfcc_min = min(mfcc, [], 1);
            mfcc_max = max(mfcc, [], 1);
            mfcc_stats = [mfcc_mean, mfcc_std, mfcc_min, mfcc_max];
            features.mfcc = mfcc_stats;

            % 计算谐波比例和过渡帧比例
            [hr, tr] = harmonic_transition_ratio(frames, fs, nFFT);
            features.harmonic_ratio = hr;
            features.transition_ratio = tr;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: FeatureDropDown
        function FeatureDropDownOpening(app, event)

        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end

        % Value changed function: FeatureDropDown
        function FeatureDropDownValueChanged(app, event)
            selectedFeature = app.FeatureDropDown.Value;
            if ~isempty(app.features)
                switch selectedFeature
                    case 'Intensity'
                        featureData = app.features.intensity;
                    case 'Energy'
                        featureData = app.features.energy;
                    case 'MFCC'
                        featureData = app.features.mfcc;
                    case 'Harmonic Ratio'
                        featureData = app.features.harmonic_ratio;
                    case 'Transition Ratio'
                        featureData = app.features.transition_ratio;
                    case 'Short-time Average Magnitude'
                        featureData = app.features.st_am;
                    case 'Short-time Zero Crossing Rate'
                        featureData = app.features.st_zcr;
                    otherwise
                        featureData = [];
                end
                if ~isempty(featureData)
                    plot(app.Axes2, featureData);
                    xlabel(app.Axes2, 'Time (samples)');
                    ylabel(app.Axes2, selectedFeature);
                else
                    cla(app.Axes);
                    title(app.Axes2, 'Feature not available');
                end
            end
        end

        % Button pushed function: ImportAudioButton
        function ImportAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav');
            if ~isequal(file, 0)
                [app.audioData, app.fs] = audioread(fullfile(path, file));
                plot(app.Axes, app.audioData);
                xlabel(app.Axes, 'Time (samples)');
                ylabel(app.Axes, 'Amplitude');
            end
        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
            if ~isempty(app.audioData)
                app.features = extractAudioFeatures(app, app.audioData, app.fs);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, '原始波形图')
            app.Axes.XTick = [];
            app.Axes.XTickLabel = '';
            app.Axes.YTick = [];
            app.Axes.YTickLabel = '';
            app.Axes2 = uiaxes(app.UIFigure);
            title(app.Axes2, '特征波形图')
            app.Axes2.XTick = [];
            app.Axes2.YTick = [];
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.FeatureDropDown = uidropdown(app.UIFigure);
            app.FeatureDropDown.Items = {'Intensity', 'Energy', 'MFCC', 'Short-time Average Magnitude', 'Short-time Zero Crossing Rate'};
            app.FeatureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FeatureDropDownOpening, true);
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDownValueChanged, true);
            app.FeatureDropDown.Value = 'Intensity';
            app.ImportAudioButton = uibutton(app.UIFigure, 'push');
            app.ImportAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioButtonPushed, true);
            app.ImportAudioButton.Text = '导入wav文件';
            app.ExtractFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Text = '提取特征';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = show

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end